classdef CorrosionDataAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlotCorrosionDataUIFigure      matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        NewMenu                        matlab.ui.container.Menu
        SelectDataDirectoryMenu        matlab.ui.container.Menu
        CloseMenu                      matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        HelpMenu_2                     matlab.ui.container.Menu
        AboutMenu                      matlab.ui.container.Menu
        AboutAppMenu                   matlab.ui.container.Menu
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        EnterLegendInfoEditField       matlab.ui.control.EditField
        EnterLegendInfoEditFieldLabel  matlab.ui.control.Label
        AvailableMatchingFilesListBox  matlab.ui.control.ListBox
        AvailableMatchingFilesListBoxLabel  matlab.ui.control.Label
        SavePlotButton                 matlab.ui.control.Button
        PlotDataButton                 matlab.ui.control.Button
        DataSelection                  matlab.ui.control.DropDown
        DataTypeDropDownLabel          matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        selDataPath % path to where Gamry data is located...
        selPlotSavePath % path to place to save plots
        appDataTypeSelected % Type of Gamry data file selected, e.g. CORPOT
        
        listOfCorrectFiles
        
        xDataForPlotting_1
        yDataForPlotting_1
        xDataForPlotting_2
        yDataForPlotting_2 

        areaOFExp
        axZmod
        axZphz
        cycpolax
        ocpax
        potdynax
        
        % =====================================================================
        % Plotting variables
        tick_label_size = 20;
        axis_label_size = 24;
        legend_label_size = 12;
        title_label_size = 20;
        plot_line_width = 3;
        axis_line_width = 3;
        marker_size = 4;
        font_weight = 'bold';
        lines1 = {':b',':r',':g',':k',':c'};
        chars1 = {'bo','r+','g*','k.','cx','ms','yd','b^','rv','g<','k>'}
        chars2 = {'kx','k*','bo','r+','g*','mo','m+','m*','b^','rv','g<','k>'};
        lines2 = {':k',':r',':g'};
        % ===================================================================== 

        bigNum = 100000;
        lowVal = -10000;        
    end
    
    methods (Access = private)

        function plotBode(app,lstring)

            spl = split(lstring,',');
            legendstring = {numel(spl)};

            for i = 1:numel(spl)
                legendstring{i} = spl{i};
            end

            t = tiledlayout(app.RightPanel,1,2);
            % Zmod vs freq
            app.axZmod = nexttile(t);
            hold(app.axZmod,'on')
            for j = 1:size(app.xDataForPlotting_1,2)
                goodvals = app.yDataForPlotting_1(:,j) > app.lowVal;
                yplots = app.yDataForPlotting_1(goodvals,j);
                xplots = app.xDataForPlotting_1(goodvals,j);     
                if j == 1 || j == 2
                    plot(app.axZmod,xplots,yplots,char(app.chars2{j}),'MarkerSize',app.marker_size,'LineWidth', app.plot_line_width-2)
                else
                    plot(app.axZmod,xplots,yplots,char(app.chars2{j}),'MarkerSize',app.marker_size,'LineWidth', app.plot_line_width)                    
                end
            end
            xlabel(app.axZmod,'Frequency (Hz)', "FontName","times")
            ylabel(app.axZmod,'Z_{mod} (\Omega)', "FontName","times")
        
            xlim(app.axZmod,[1.0e-2 1.0e6])
            xticks(app.axZmod,[1e-2 1e-1 1e0 1e1 1e2 1e3 1e4 1e5])
            xticklabels(app.axZmod,{'10^{-2}','10^{-1}','10^0','10^1','10^2','10^3','10^4','10^5'})            
        
            box(app.axZmod,'on')
            
            % ax = gca;
            app.axZmod.FontName = 'times';
            app.axZmod.XScale = 'log';
            app.axZmod.YScale = 'log';
            app.axZmod.FontSize = app.tick_label_size;
            app.axZmod.FontWeight = app.font_weight;
            app.axZmod.LineWidth  = app.axis_line_width;
            app.axZmod.XMinorTick = "on";
            app.axZmod.YMinorTick = "on";  
        
            legend(app.axZmod,legendstring,'Location','best', "FontName","times")
            legend(app.axZmod,'boxoff') 
        
            axis(app.axZmod,'square')     
            hold(app.axZmod,'off')
        
            % Phase vs freq
            app.axZphz = nexttile(t);
            hold(app.axZphz,'on')
            
            for j = 1:size(app.xDataForPlotting_1,2)
                goodvals = app.yDataForPlotting_2(:,j) > app.lowVal;
                yplots = app.yDataForPlotting_2(goodvals,j);
                xplots = app.xDataForPlotting_2(goodvals,j);  
                if j == 1 || j == 2
                    plot(app.axZphz,xplots,yplots,char(app.chars2{j}),'MarkerSize',app.marker_size,'LineWidth', app.plot_line_width-2)
                else
                    plot(app.axZphz,xplots,yplots,char(app.chars2{j}),'MarkerSize',app.marker_size,'LineWidth', app.plot_line_width)
                end
            end

            xlabel(app.axZphz,'Frequency (Hz)', "FontName","times")
            ylabel(app.axZphz,'Phase (^o)', "FontName","times")
        
            xlim(app.axZphz,[1.0e-2 1.0e6])
            ylim(app.axZphz,[-90 0])
        
            xticks(app.axZphz,[1e-2 1e-1 1e0 1e1 1e2 1e3 1e4 1e5])
            xticklabels(app.axZphz,{'10^{-2}','10^{-1}','10^0','10^1','10^2','10^3','10^4','10^5'})

            box(app.axZphz,'on')
            % ax = gca;
            app.axZphz.FontName = 'times';
            app.axZphz.XScale = 'log';            
            app.axZphz.FontSize = app.tick_label_size;
            app.axZphz.FontWeight = app.font_weight;
            app.axZphz.LineWidth  = app.axis_line_width;
            app.axZphz.XMinorTick = "on";
            app.axZphz.YMinorTick = "on";  
        
            axis(app.axZphz,'square')     
            hold(app.axZphz,'off')  
            
        end
            
        function plotCycpol(app,lstring)
            spl = split(lstring,',');
            legendstring = {numel(spl)};

            for i = 1:numel(spl)
                legendstring{i} = spl{i};
            end

            t = tiledlayout(app.RightPanel,1,1);
            app.cycpolax = nexttile(t);

            hold(app.cycpolax,'on')
            
            for j = 1:size(app.xDataForPlotting_1,2)
                goodvals = app.yDataForPlotting_1(:,j) > app.lowVal;
                yplots = app.yDataForPlotting_1(goodvals,j);
                xplots = app.xDataForPlotting_1(goodvals,j);

                plot(app.cycpolax,abs(xplots),yplots,char(app.chars1{j}),'MarkerSize',app.marker_size,'LineWidth', app.plot_line_width)
            end            
        
            box(app.cycpolax,'on')
        
            legend(app.cycpolax,legendstring,'Location','best')
            legend(app.cycpolax,'boxoff') 
        
            xlabel(app.cycpolax,'Current density (A/cm^2)')
	        ylabel(app.cycpolax,'Potential (V_{Ag/AgCl})')

            app.cycpolax.XScale = 'log';
            app.cycpolax.FontName = 'Times';
            app.cycpolax.FontSize = app.tick_label_size;
            app.cycpolax.FontWeight = app.font_weight;
            app.cycpolax.LineWidth = app.axis_line_width;
            app.cycpolax.XMinorTick = 'on';
            app.cycpolax.YMinorTick = 'on';  

            axis(app.cycpolax,'square')     
            hold(app.cycpolax,'off')                 
        end
            
        function plotOCP(app,lstring)
            spl = split(lstring,',');
            legendstring = {numel(spl)};

            for i = 1:numel(spl)
                legendstring{i} = spl{i};
            end

            t = tiledlayout(app.RightPanel,1,1);
            app.ocpax = nexttile(t);
            hold(app.ocpax,'on')
        
            for j = 1:size(app.xDataForPlotting_1,2)
                goodvals = app.yDataForPlotting_1(:,j) > app.lowVal;
                yplots = app.yDataForPlotting_1(goodvals,j);
                xplots = app.xDataForPlotting_1(goodvals,j);
                plot(app.ocpax,xplots,yplots,char(app.chars1{j}),'MarkerSize',app.marker_size,'LineWidth', app.plot_line_width)
            end 
        
            axis(app.ocpax,'square')
            box(app.ocpax,'on')
        
            legend(app.ocpax,legendstring,'Location','best')
            legend(app.ocpax,'boxoff') 
        
            xlabel(app.ocpax,'Time (a)')
            ylabel(app.ocpax,'Potential (V_{Ag/AgCl})')
        
            % xlim([1.0e-9 1.0e-1])
            % ylim([dnPot upPot])
        
            % ocpax.XScale = 'log';
            app.ocpax.FontName = 'Times';
            app.ocpax.FontSize = app.tick_label_size;
            app.ocpax.FontWeight = app.font_weight;
            app.ocpax.LineWidth = app.axis_line_width;
            app.ocpax.XMinorTick = 'on';
            app.ocpax.YMinorTick = 'on';  

            axis(app.ocpax,'square')        
            hold(app.ocpax,'off')              
    end

        function plotPotdyn(app,lstring)
            spl = split(lstring,',');
            legendstring = {numel(spl)};

            for i = 1:numel(spl)
                legendstring{i} = spl{i};
            end

            t = tiledlayout(app.RightPanel,1,1);
            app.potdynax = nexttile(t);

            hold(app.potdynax,'on')
            
            for j = 1:size(app.xDataForPlotting_1,2)
                goodvals = app.yDataForPlotting_1(:,j) > app.lowVal;
                yplots = app.yDataForPlotting_1(goodvals,j);
                xplots = app.xDataForPlotting_1(goodvals,j);

                plot(app.potdynax,abs(xplots),yplots,char(app.chars1{j}),'MarkerSize',app.marker_size,'LineWidth', app.plot_line_width)
            end            
        
            box(app.potdynax,'on')
        
            legend(app.potdynax,legendstring,'Location','best')
            legend(app.potdynax,'boxoff') 
        
            xlabel(app.potdynax,'Current density (A/cm^2)')
	        ylabel(app.potdynax,'Potential (V_{Ag/AgCl})')

            app.potdynax.XScale = 'log';
            app.potdynax.FontName = 'Times';
            app.potdynax.FontSize = app.tick_label_size;
            app.potdynax.FontWeight = app.font_weight;
            app.potdynax.LineWidth = app.axis_line_width;
            app.potdynax.XMinorTick = 'on';
            app.potdynax.YMinorTick = 'on';  

            axis(app.potdynax,'square')     
            hold(app.potdynax,'off')                 
        end
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
            delete(app)           
        end

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
            delete(app.RightPanel.Children);
            app.AvailableMatchingFilesListBox.Items = "No files";
            app.AvailableMatchingFilesListBox.Enable = "off";
            app.EnterLegendInfoEditField.Value = "";
            app.EnterLegendInfoEditField.Enable = "off";
            app.DataSelection.Enable = "off";
            app.PlotDataButton.Enable = "off";
            app.SavePlotButton.Enable = "off";
        end

        % Menu selected function: SelectDataDirectoryMenu
        function SelectDataDirectoryMenuSelected(app, event)
            title = 'Select a folder containing Gamry *.dta files';
            app.selDataPath = uigetdir(pwd, title);
            app.DataSelection.Enable = "on";
        end

        % Value changed function: DataSelection
        function DataSelectionValueChanged(app, event)
            gamryDatafileType = app.DataSelection.Value;
            gamrydatafileext1 = 'dta';
            gamrydatafileext2 = 'DTA';
            app.appDataTypeSelected = app.DataSelection.Value;
            datafiles = dir(app.selDataPath);
            lyric1 = 'TAG';
            app.AvailableMatchingFilesListBox.Items = {};            
            app.listOfCorrectFiles = {};

            fig = uifigure;
            d = uiprogressdlg(fig,'Title','Please Wait',...
                'Message','Getting the files...');

            for k = 1:numel(datafiles)
                isoname = split(datafiles(k).name,'.');
                ext = char(isoname{2});
                if ~datafiles(k).isdir && (strcmp(ext,gamrydatafileext1) || strcmp(ext,gamrydatafileext2))
                    fn = datafiles(k).name;
                    ffn = fullfile(datafiles(k).folder,fn);
                    
                    [fid,msg] = fopen(ffn,'rt');
                    assert(fid>=3,msg)
                
                    while ~feof(fid)
                        st = fgetl(fid);    
                        if contains(st,lyric1)
                            stread = split(st);
                            typefile = char(stread{2,1});    
        
                            if strcmp(gamryDatafileType,typefile)                                   
                                app.AvailableMatchingFilesListBox.Items{end+1} = ffn;
                            end
                        end        
                    end
                    fclose(fid);
                    d.Value = k/numel(datafiles);
                end
            end
            app.AvailableMatchingFilesListBox.Enable = "on"; 
            close(d)
            delete(fig)
        end

        % Button pushed function: PlotDataButton
        function PlotDataButtonPushed(app, event)
            Nfiles = numel(app.listOfCorrectFiles);
            
            app.xDataForPlotting_1 = zeros(app.bigNum,Nfiles);
            app.yDataForPlotting_1 = ones(app.bigNum,Nfiles).*app.lowVal;            
            
            fig = uifigure;
            d = uiprogressdlg(fig,'Title','Please Wait',...
                'Message','Analyzing and plotting the data...');

            switch app.DataSelection.Value
                case 'CYCPOL'
                    app.areaOFExp = zeros(1,Nfiles);
                    for i = 1:Nfiles
                        [area,cycpoldata] = AnalyzeGamryCYCPOLData_2(char(app.listOfCorrectFiles{i}));

                        ndatapoints = numel(cycpoldata.Im);
                        app.xDataForPlotting_1(1:ndatapoints,i) = cycpoldata.Im./area;
                        app.yDataForPlotting_1(1:ndatapoints,i) = cycpoldata.Vf;

                        app.areaOFExp(1,i) = area;
                        d.Value = i/Nfiles;

                    end                                           
                case 'EISPOT'
                    app.areaOFExp = zeros(1,Nfiles);                    
                    for i = 1:Nfiles
                        [area,eisdata] = AnalyzeGamryEISData(char(app.listOfCorrectFiles{i}));

                        ndatapoints = numel(eisdata.Freq);
                        app.xDataForPlotting_1(1:ndatapoints,i) = eisdata.Freq;
                        app.yDataForPlotting_1(1:ndatapoints,i) = eisdata.Zmod;

                        app.xDataForPlotting_2(1:ndatapoints,i) = eisdata.Freq;
                        app.yDataForPlotting_2(1:ndatapoints,i) = eisdata.Zphz;

                        app.areaOFExp(1,i) = area;

                        d.Value = i/Nfiles;
                    end                    

                case 'CORPOT'                 
                    for i = 1:Nfiles
                        ocpdata = AnalyzeGamryCORPOTData(char(app.listOfCorrectFiles{i}));

                        ndatapoints = numel(ocpdata.T);
                        app.xDataForPlotting_1(1:ndatapoints,i) = ocpdata.T;
                        app.yDataForPlotting_1(1:ndatapoints,i) = ocpdata.Vf;

                        d.Value = i/Nfiles;
                    end
                case 'POTENTIODYNAMIC'
                    app.areaOFExp = zeros(1,Nfiles);
                    for i = 1:Nfiles
                        [area,potdyndata] = AnalyzeGamryPOTDYNData(char(app.listOfCorrectFiles{i}));

                        ndatapoints = numel(potdyndata.Im);
                        app.xDataForPlotting_1(1:ndatapoints,i) = potdyndata.Im./area;
                        app.yDataForPlotting_1(1:ndatapoints,i) = potdyndata.Vf;

                        app.areaOFExp(1,i) = area;
                        d.Value = i/Nfiles;

                    end   
                otherwise
                    [area,eisdata] = AnalyzeGamryEISData(app,char(app.listOfCorrectFiles{i}));
            end   

            % legendstring = cell(1,Nfiles);
            % for j = 1:Nfiles
            %     ls1 = split(char(app.listOfCorrectFiles{j}),'.');
            %     ls2 = split(ls1(1),'\');
            %     legendstring{j} = char(ls2(end));
            % end
            legendstring = app.EnterLegendInfoEditField.Value;
            switch app.appDataTypeSelected
                case 'CYCPOL'
                    plotCycpol(app,legendstring)
                    app.SavePlotButton.Enable = "on";
                case 'EISPOT'
                    plotBode(app,legendstring);
                    app.SavePlotButton.Enable = "on";
                case 'CORPOT'
                    plotOCP(app,legendstring);
                    app.SavePlotButton.Enable = "on";
                case 'POTENTIODYNAMIC'
                    plotPotdyn(app,legendstring);
                    app.SavePlotButton.Enable = "on";                    
            end
            close(d)
            delete(fig)
        end

        % Button pushed function: SavePlotButton
        function SavePlotButtonPushed(app, event)
            filter = {'*.png';'*.jpg';'*.gif';'*.*'};
            [fileWext, location] = uiputfile(filter);            
            app.selPlotSavePath = fullfile(location,fileWext);     

            files = split(fileWext,'.');
            file = files{1};
            graphicsext = '.png';

            switch app.appDataTypeSelected
                case 'CYCPOL'
                    gsfn1 = fullfile(location,strcat(file,'_cycpol',graphicsext));
                    exportgraphics(app.cycpolax,gsfn1,'Resolution',300)
                    for j = 1:size(app.xDataForPlotting_1,2)
                        goodvals = app.yDataForPlotting_1(:,j) > app.lowVal;
                        potential = app.yDataForPlotting_1(goodvals,j);
                        currentdensity = app.xDataForPlotting_1(goodvals,j);
    
                        tfn = strcat(file,'_forOrigin',num2str(j),'.csv');
                        A = [currentdensity,potential];
                        T = array2table(A);
                        
                        T.Properties.VariableNames{1} = strcat('i(A/cm2)',num2str(j));
                        T.Properties.VariableNames{2} = strcat('V(Vvsref)',num2str(j));

                        writetable(T,tfn);                        
                    end 

                case 'EISPOT'
                    gsfn1 = fullfile(location,strcat(file,'_zmod',graphicsext));
                    gsfn2 = fullfile(location,strcat(file,'_zphz',graphicsext));                    
                    exportgraphics(app.axZmod,gsfn1,'Resolution',300)
                    exportgraphics(app.axZphz,gsfn2,'Resolution',300)

                    for j = 1:size(app.xDataForPlotting_1,2)
                        goodvals = app.yDataForPlotting_1(:,j) > app.lowVal;
                        zmod = app.yDataForPlotting_1(goodvals,j);
                        freq = app.xDataForPlotting_1(goodvals,j);
                        phase = app.yDataForPlotting_2(goodvals,j);
    
                        tfn = strcat(file,'_forOrigin',num2str(j),'.csv');
                        A = [freq,zmod,phase];
                        T = array2table(A);
                        
                        T.Properties.VariableNames{1} = 'Freq(Hz)';
                        T.Properties.VariableNames{2} = 'Zmod(Ohms)';
                        T.Properties.VariableNames{3} = 'Phase(Degrees)';
    
                        writetable(T,tfn);                                        
                    end             

                case 'CORPOT'
                    gsfn1 = fullfile(location,strcat(file,'_ocp',graphicsext));
                    exportgraphics(app.ocpax,gsfn1,'Resolution',300)

                    for j = 1:size(app.xDataForPlotting_1,2)
                        goodvals = app.xDataForPlotting_1(:,j) > app.lowVal;
                        time = app.xDataForPlotting_1(goodvals,j);
                        potential = app.yDataForPlotting_1(goodvals,j);                        
    
                        tfn = strcat(file,'_forOrigin',num2str(j),'.csv');
                        A = [time,potential];
                        T = array2table(A);
                        
                        T.Properties.VariableNames{1} = 'Time(s)';
                        T.Properties.VariableNames{2} = 'V(Vvsref)';

                        writetable(T,tfn);                        
                    end     
                case 'POTENTIODYNAMIC'
                    gsfn1 = fullfile(location,strcat(file,'_potdyn',graphicsext));
                    exportgraphics(app.potdynax,gsfn1,'Resolution',300)
                    for j = 1:size(app.xDataForPlotting_1,2)
                        goodvals = app.yDataForPlotting_1(:,j) > app.lowVal;
                        potential = app.yDataForPlotting_1(goodvals,j);
                        currentdensity = app.xDataForPlotting_1(goodvals,j);
    
                        tfn = strcat(file,'_forOrigin',num2str(j),'.csv');
                        A = [currentdensity,potential];
                        T = array2table(A);
                        
                        T.Properties.VariableNames{1} = strcat('i(A/cm2)',num2str(j));
                        T.Properties.VariableNames{2} = strcat('V(Vvsref)',num2str(j));

                        writetable(T,tfn);                        
                    end                     
            end            
        end

        % Value changed function: AvailableMatchingFilesListBox
        function AvailableMatchingFilesListBoxValueChanged(app, event)
            app.listOfCorrectFiles = app.AvailableMatchingFilesListBox.Value;
            if numel(app.listOfCorrectFiles ) >= 1
                app.PlotDataButton.Enable = "on";
                app.EnterLegendInfoEditField.Enable = "on";
            else
                app.EnterLegendInfoEditField.Enable = "off";
                app.PlotDataButton.Enable = 'off';
            end
        end

        % Menu selected function: AboutAppMenu
        function AboutAppMenuSelected(app, event)
             fig = uifigure;       
             fig.Name = 'About';
             fig.Icon = './MLApp1.png';
             C = imread('MLApp1.png');             

             % ha = axes('units','normalized','position',[0.25 0.25 0.5 0.5]);
             % uistack(ha,'bottom');
             % hi = imagesc(C);
             % set(ha,'handlevisibility','off','visible','off')
          
             txt = uitextarea(fig);
             txt.HorizontalAlignment = 'center';
             txt.FontSize = app.axis_label_size-4;
             txt.Position(1:2) = [100 250];
             txt.Position(3:4) = [400 150];
             txtmsg = sprintf("Corrosion Data Plotting 1.5 \n(c) 2024 \nCenter for Corrosion Science and Engineering");
             txt.Value = txtmsg; 
        end

        % Menu selected function: HelpMenu_2
        function HelpMenu_2Selected(app, event)
             fig = uifigure;       
             fig.Name = 'Help';
             fig.Icon = './MLApp1.png';
             txt = uitextarea(fig);
             txt.HorizontalAlignment = 'center';
             txt.FontSize = app.axis_label_size-4;
             txt.Position(1:2) = [100 250];
             txt.Position(3:4) = [400 150];
             txtmsg = sprintf("Help file to be entered here...");
             txt.Value = txtmsg;             
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.PlotCorrosionDataUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {791, 791};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {437, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PlotCorrosionDataUIFigure = uifigure('Visible', 'off');
            app.PlotCorrosionDataUIFigure.Name = 'Plot Corrosion Data';
            app.PlotCorrosionDataUIFigure.Icon = fullfile(pathToMLAPP, 'MLApp1.png');
            app.PlotCorrosionDataUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.PlotCorrosionDataUIFigure);
            app.FileMenu.Tooltip = {'Select to get started...'};
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Tooltip = {'Resets the app to start over or start new...'};
            app.NewMenu.Accelerator = 'N';
            app.NewMenu.Text = 'New';
            app.SelectDataDirectoryMenu = uimenu(app.FileMenu);
            app.SelectDataDirectoryMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectDataDirectoryMenuSelected, true);
            app.SelectDataDirectoryMenu.Tooltip = {'Select to find a directory with data files...'};
            app.SelectDataDirectoryMenu.Accelerator = 'D';
            app.SelectDataDirectoryMenu.Text = 'Select Data Directory';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Tooltip = {'Close the app...'};
            app.CloseMenu.Accelerator = 'x';
            app.CloseMenu.Text = 'Close';
            app.HelpMenu = uimenu(app.PlotCorrosionDataUIFigure);
            app.HelpMenu.Tooltip = {'Help'};
            app.HelpMenu.Text = 'Help';
            app.HelpMenu_2 = uimenu(app.HelpMenu);
            app.HelpMenu_2.MenuSelectedFcn = createCallbackFcn(app, @HelpMenu_2Selected, true);
            app.HelpMenu_2.Accelerator = 'h';
            app.HelpMenu_2.Text = 'Help';
            app.AboutMenu = uimenu(app.PlotCorrosionDataUIFigure);
            app.AboutMenu.Tooltip = {'About App'};
            app.AboutMenu.Text = 'About';
            app.AboutAppMenu = uimenu(app.AboutMenu);
            app.AboutAppMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutAppMenuSelected, true);
            app.AboutAppMenu.Text = 'About App';
            app.GridLayout = uigridlayout(app.PlotCorrosionDataUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DataTypeDropDownLabel = uilabel(app.LeftPanel);
            app.DataTypeDropDownLabel.Enable = 'off';
            app.DataTypeDropDownLabel.Text = 'Data Type';
            app.DataSelection = uidropdown(app.LeftPanel);
            app.DataSelection.Items = {'Pick experiment  type', 'CYCPOL', 'EISPOT', 'CORPOT', 'POTENTIODYNAMIC'};
            app.DataSelection.ValueChangedFcn = createCallbackFcn(app, @DataSelectionValueChanged, true);
            app.DataSelection.Enable = 'off';
            app.DataSelection.Value = 'Pick experiment  type';
            app.PlotDataButton = uibutton(app.LeftPanel, 'push');
            app.PlotDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDataButtonPushed, true);
            app.PlotDataButton.Enable = 'off';
            app.PlotDataButton.Tooltip = {'Click to plot the selected data files in the right panel...'};
            app.PlotDataButton.Text = 'Plot Data';
            app.SavePlotButton = uibutton(app.LeftPanel, 'push');
            app.SavePlotButton.ButtonPushedFcn = createCallbackFcn(app, @SavePlotButtonPushed, true);
            app.SavePlotButton.Enable = 'off';
            app.SavePlotButton.Tooltip = {'Click to save the graphic and CSV files of the data...'};
            app.SavePlotButton.Text = 'Save Plot';
            app.AvailableMatchingFilesListBoxLabel = uilabel(app.LeftPanel);
            app.AvailableMatchingFilesListBoxLabel.Enable = 'off';
            app.AvailableMatchingFilesListBoxLabel.Text = 'Available Matching Files';
            app.AvailableMatchingFilesListBox = uilistbox(app.LeftPanel);
            app.AvailableMatchingFilesListBox.Items = {'No files'};
            app.AvailableMatchingFilesListBox.Multiselect = 'on';
            app.AvailableMatchingFilesListBox.ValueChangedFcn = createCallbackFcn(app, @AvailableMatchingFilesListBoxValueChanged, true);
            app.AvailableMatchingFilesListBox.Enable = 'off';
            app.AvailableMatchingFilesListBox.Tooltip = {'Select files for plotting.'};
            app.AvailableMatchingFilesListBox.Value = {'No files'};
            app.EnterLegendInfoEditFieldLabel = uilabel(app.LeftPanel);
            app.EnterLegendInfoEditFieldLabel.Text = 'Enter Legend Info';
            app.EnterLegendInfoEditField = uieditfield(app.LeftPanel, 'text');
            app.EnterLegendInfoEditField.Enable = 'off';
            app.EnterLegendInfoEditField.Tooltip = {'Enter text for legend entries, separated by commas'};
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.PlotCorrosionDataUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CorrosionDataAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PlotCorrosionDataUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlotCorrosionDataUIFigure)
        end
    end
end